classdef ComPortComms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        aEditField                matlab.ui.control.EditField
        aEditFieldLabel           matlab.ui.control.Label
        GridLayout                matlab.ui.container.GridLayout
        TX                        matlab.ui.control.Button
        CPUtempGauge              matlab.ui.control.Gauge
        CPUtempGaugeLabel         matlab.ui.control.Label
        ClearButton_tx            matlab.ui.control.Button
        xDataEditField_tx         matlab.ui.control.EditField
        dataLabel                 matlab.ui.control.Label
        nDataEditField_tx         matlab.ui.control.EditField
        nDataEditField_2Label     matlab.ui.control.Label
        iDEditField_tx            matlab.ui.control.EditField
        iDEditField_2Label        matlab.ui.control.Label
        PotenciometerField        matlab.ui.control.EditField
        PotenciometervalueLabel   matlab.ui.control.Label
        Alive                     matlab.ui.control.EditField
        AlivetimerEditFieldLabel  matlab.ui.control.Label
        PortField                 matlab.ui.control.EditField
        PortEditFieldLabel        matlab.ui.control.Label
        xDataEditField_rx         matlab.ui.control.EditField
        DataLabel                 matlab.ui.control.Label
        nDataEditField_rx         matlab.ui.control.EditField
        nDataEditFieldLabel       matlab.ui.control.Label
        iDEditField_rx            matlab.ui.control.EditField
        iDEditFieldLabel          matlab.ui.control.Label
        ReceiveLabel              matlab.ui.control.Label
        TransmitLabel             matlab.ui.control.Label
        ClearButton_rx            matlab.ui.control.Button
        CloseButton               matlab.ui.control.Button
        ConnectionStatus          matlab.ui.control.Lamp
        PlatformSwitch            matlab.ui.control.Switch
        LoadButton                matlab.ui.control.Button
        SendButton                matlab.ui.control.Button
        OpenButton                matlab.ui.control.Button
        potenciometerGraph        matlab.ui.control.UIAxes
        Graph                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % seriovy port objekt
        s_port = '/dev/ttyACM1' % seriovy port
        Timer % casovac pro vzorkovani
        
        calls = 0;
        errors = 0; 

        % RX
        id;
        elements;
        dataBuffer;
        
        % TX 
        tx_buffer; % buffer for the tx data
        tx_buffer_elements = 0; % number of datas in the buffer

        iD string % id nactenych dat
        nData string % delka nacitanych dat
        xData string % nacitana data

        test int32 = 0;

        x = -1.75:0.01:1.75; % pro graf
        a; % koeficient pro graf

        eTime;
        
        connected = 0 % Description

        fs; % vzorkovaci frekvence
        t; % vzorkovaci perioda
        n; % pocet vzorku
    
        xGraphPlot = 0:0.1:10;
        yGraphPlot = zeros(1,101);

        %singleSample = zeros(0,10);
        singleSample;
    end
    
    methods (Access = private)

        function SerialPortCallBack010(app, src, ~)

            tic;

            app.calls = app.calls + 1;
            app.iDEditField_rx.Value = string(app.calls);

            try
                [app.id, app.elements, app.dataBuffer] = readDataSTM32(src);
                
                if ~isempty(app.dataBuffer)
                    % Zpracuj data podle potřeby
                    % for i = 1:numel(app.dataBuffer)
                    %     fprintf('ID: %d, Hodnoty: %s\n', app.dataBuffer(i).id, mat2str(app.dataBuffer(i).values));
                    % end

                    switch app.id
                        case 0
                            for x = 1:app.elements
                                % inner data switch
                                switch app.dataBuffer(x).data_id
                                    case 1
                                        % alive timer
                                        app.Alive.Value = string(app.dataBuffer(x).data);
                                    case 2
                                        % cpu temp
                                        app.CPUtempGauge.Value = app.dataBuffer(x).data;
                                    case 23
                                        % potenciometer
                                        app.PotenciometerField.Value = string(app.dataBuffer(x).data);
                                end


                            end


                        otherwise
                            error("Unsupported buffer id: %d", app.id);
                    end
                end

                clear id;
                clear elements;
                clear dataBuffer;

                elapsedTime = toc;
                %fprintf('Callback took: %.6f seconds\n', elapsedTime);
                t = clock; % Vrátí pole s rokem, měsícem, dnem, hodinou, minutou a sekundami
                %fprintf('Aktuální čas: %02d:%02d:%02.2f\n', t(4), t(5), t(6));

                return

            catch ME
                app.errors = app.errors + 1;
                disp(ME.message) % rethrow(ME)
            end
            
        end
        
        function update_connection_status(app)
            % aktualizuje diodu statusu aktualniho spojeni
            switch app.connected
                case 1
                    app.ConnectionStatus.Color = 'green';
                case 0
                    app.ConnectionStatus.Color = [0.90 0.90 0.90];
                case -1
                    app.ConnectionStatus.Color = 'red';
            end
        end
        
        function TimerPeriodCallback(app, ~, ~)

            % posun prvku a pridani noveho na konec
            app.yGraphPlot = [app.yGraphPlot(2:end), app.singleSample];

            % vykresleni grafu
            plot(app.potenciometerGraph, app.yGraphPlot);
            
            % nastaveni meze
            app.potenciometerGraph.YLim = ([0 4096]);
        end
        
        function results = func4(app)
            
        end
        
        function reset_buffer(app)
            % also serves as an initialization
            % new buffer
            app.tx_buffer = uint8([]);
            app.tx_buffer_elements = 0;
        end

        function comms_append_int32(app, data_id, data_count, data)
            % increment data
            app.tx_buffer_elements = app.tx_buffer_elements + 1;
            
            % append id, data size and data count
            app.tx_buffer = [app.tx_buffer, uint8(data_id)];
            app.tx_buffer = [app.tx_buffer, uint8(4)];
            app.tx_buffer = [app.tx_buffer, uint8(data_count)];
            
            %
            for i = 1:data_count
                app.tx_buffer = [app.tx_buffer, typecast(uint32(data(i)),'uint8')];
            end
        end

        function comms_send(app)
            % add buffer id and data count
            start_bytes = typecast(uint16(hex2dec('ABCD')), 'uint8');
            num_elements = typecast(uint16(app.tx_buffer_elements), 'uint8');

            app.tx_buffer = [start_bytes, uint8(0), num_elements, app.tx_buffer];

            disp(app.tx_buffer)
            disp(length(app.tx_buffer))
            writeDataSTM32(app.s,app.tx_buffer);

            reset_buffer(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % vzorkovani
            app.fs = 10; % vzorkovaci frekvence - sekunda
            app.t = 1 / app.fs; % vzorkovaci perioda
            app.n = 10 * app.fs; % pocet vzorku pro deset sekund

            % inicializace casovace
            app.Timer = timer('ExecutionMode','fixedRate', 'Period', app.t, 'TimerFcn', @app.TimerPeriodCallback); 

            % init tx_buffer
            reset_buffer(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            try
                if app.connected < 1
                    % napojeni na serial port
                    
                    % zapnuti casovace
                    app.Timer.start();
                    
                    % nastaveni rychlosti, target 230400 (2*115200)
                    %app.s = serialport(app.s_port, 4*115200);
                    %app.s = serialport(app.s_port, 115200, "FlowControl", "hardware");
                    
                    %configureTerminator(app.s, "CR/LF");
                    %configureCallback(app.s ,"terminator", @app.SerialPortCallBack010);
                    app.s = serialport(app.s_port, 115200, ...
                        'DataBits', 8, ...
                        'StopBits', 1, ...
                        'Parity', 'none', ...
                        'FlowControl', 'hardware');
                    flush(app.s)
                    configureTerminator(app.s, "CR/LF");
                    configureCallback(app.s, "terminator", @(src, event) app.SerialPortCallBack010(src, event));
                    
                    % aktualizace statusu napojeni
                    app.connected = 1;
                    app.update_connection_status;
                    
                    % zmena tlacitek
                    app.OpenButton.Enable = "off";
                    app.CloseButton.Enable = "on";
                end
            catch ME

                % doslo k chybe, port nenexistuje / je jiz pouzivan
                disp(ME.message)
                app.ConnectionStatus.Color = 'red';
                app.Timer.stop();
                
                % aktualizace statusu
                app.connected = -1;
                app.update_connection_status;
            end 
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            
            %x = 1:10;
            %x = randi([0, 64], 1, 10);
            %writeDataSTM32(app.s, 20, 10, x)

            idtx = str2num(app.iDEditField_tx.Value)
            ntx = str2num(app.nDataEditField_tx.Value)
            xtx = str2num(app.xDataEditField_tx.Value)

            class(idtx)
            class(ntx)
            class(xtx)

            %writeDataSTM32(app.s, idtx, ntx, xtx)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % manualne vyvola nacteni dat
            [app.iD, app.nData, app.xData] = readDataSTM32(app.s);

            app.iDEditField_rx.Value = app.iD;
            app.nDataEditField_rx.Value = app.nData;
            app.xDataEditField_rx.Value = app.xData;
        end

        % Value changing function: iDEditField_rx
        function iDEditField_rxValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: PlatformSwitch
        function PlatformSwitchValueChanged(app, event)
            % funkce predvyplnuje serial port

            value = app.PlatformSwitch.Value;
            
            if strcmp(value,'Linux')
                %disp('Linux')
                port = '/dev/ttyACM1';
            elseif strcmp(value,'Windows')
                %disp('Windows')
                port = 'COM5';
            end
            
            app.s_port = port;
            app.PortField.Value = port;
        end

        % Value changed function: PortField
        function PortFieldValueChanged(app, event)
            value = app.PortField.Value;
            
            app.s_port = value;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % ukonci spojeni
            app.Timer.stop();
            delete(app.s);
            
            % prepne tlacitka
            app.OpenButton.Enable = "on";
            app.CloseButton.Enable = "off";
            
            % aktualizuje status
            app.connected = 0;
            app.update_connection_status;
        end

        % Button pushed function: ClearButton_rx
        function ClearButton_rxPushed(app, event)
            app.iDEditField_rx.Value = '';
            app.nDataEditField_rx.Value = '';
            app.xDataEditField_rx.Value = '';
        end

        % Button pushed function: ClearButton_tx
        function ClearButton_txPushed(app, event)
            app.iDEditField_tx.Value = "";
            app.nDataEditField_tx.Value = "";
            app.xDataEditField_tx.Value = "";
        end

        % Button pushed function: TX
        function TXButtonPushed(app, event)
            comms_append_int32(app,5,1,1);
            comms_send(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'abs(x).^(2/3) + 0.9 .* (3.3 - abs(x).^2).^(1/2) .* sin(a .* pi .* abs(x))')
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.Graph.XLim = [-3 3];
            app.Graph.YLim = [-2 3];
            app.GridLayout = uigridlayout(app.UIFigure);
            app.potenciometerGraph = uiaxes(app.GridLayout);
            title(app.potenciometerGraph, 'Potentiometer sampling frequency')
            xlabel(app.potenciometerGraph, 'Time (s)')
            ylabel(app.potenciometerGraph, 'Potenciometer value')
            zlabel(app.potenciometerGraph, 'Z')
            app.potenciometerGraph.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SendButton = uibutton(app.GridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlatformSwitch = uiswitch(app.GridLayout, 'slider');
            app.PlatformSwitch.Items = {'Linux', 'Windows'};
            app.PlatformSwitch.ValueChangedFcn = createCallbackFcn(app, @PlatformSwitchValueChanged, true);
            app.PlatformSwitch.Value = 'Linux';
            app.ConnectionStatus = uilamp(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.ClearButton_rx = uibutton(app.GridLayout, 'push');
            app.ClearButton_rx.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_rxPushed, true);
            app.ClearButton_rx.Text = 'Clear';
            app.TransmitLabel = uilabel(app.GridLayout);
            app.TransmitLabel.Text = 'Transmit';
            app.ReceiveLabel = uilabel(app.GridLayout);
            app.ReceiveLabel.Text = 'Receive';
            app.iDEditFieldLabel = uilabel(app.GridLayout);
            app.iDEditFieldLabel.Text = 'iD';
            app.iDEditField_rx = uieditfield(app.GridLayout, 'text');
            app.iDEditField_rx.ValueChangingFcn = createCallbackFcn(app, @iDEditField_rxValueChanging, true);
            app.iDEditField_rx.Editable = 'off';
            app.nDataEditFieldLabel = uilabel(app.GridLayout);
            app.nDataEditFieldLabel.Text = 'nData';
            app.nDataEditField_rx = uieditfield(app.GridLayout, 'text');
            app.nDataEditField_rx.Editable = 'off';
            app.DataLabel = uilabel(app.GridLayout);
            app.DataLabel.Text = 'Data';
            app.xDataEditField_rx = uieditfield(app.GridLayout, 'text');
            app.xDataEditField_rx.Editable = 'off';
            app.PortEditFieldLabel = uilabel(app.GridLayout);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortField = uieditfield(app.GridLayout, 'text');
            app.PortField.ValueChangedFcn = createCallbackFcn(app, @PortFieldValueChanged, true);
            app.PortField.Value = '/dev/ttyACM1';
            app.AlivetimerEditFieldLabel = uilabel(app.GridLayout);
            app.AlivetimerEditFieldLabel.Text = 'Alive timer';
            app.Alive = uieditfield(app.GridLayout, 'text');
            app.Alive.Editable = 'off';
            app.Alive.Value = '0';
            app.PotenciometervalueLabel = uilabel(app.GridLayout);
            app.PotenciometervalueLabel.Text = 'Potentiometer ';
            app.PotenciometerField = uieditfield(app.GridLayout, 'text');
            app.PotenciometerField.Editable = 'off';
            app.iDEditField_2Label = uilabel(app.GridLayout);
            app.iDEditField_2Label.Text = 'iD';
            app.iDEditField_tx = uieditfield(app.GridLayout, 'text');
            app.iDEditField_tx.Value = '20';
            app.nDataEditField_2Label = uilabel(app.GridLayout);
            app.nDataEditField_2Label.Text = 'nData';
            app.nDataEditField_tx = uieditfield(app.GridLayout, 'text');
            app.nDataEditField_tx.Value = '10';
            app.dataLabel = uilabel(app.GridLayout);
            app.dataLabel.Text = 'Data';
            app.xDataEditField_tx = uieditfield(app.GridLayout, 'text');
            app.xDataEditField_tx.Value = '1 2 3 4 5 6 7 8 9 10';
            app.ClearButton_tx = uibutton(app.GridLayout, 'push');
            app.ClearButton_tx.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_txPushed, true);
            app.ClearButton_tx.Text = 'Clear';
            app.CPUtempGaugeLabel = uilabel(app.GridLayout);
            app.CPUtempGaugeLabel.Text = 'CPU temp';
            app.CPUtempGauge = uigauge(app.GridLayout, 'circular');
            app.CPUtempGauge.Limits = [-25 75];
            app.TX = uibutton(app.GridLayout, 'push');
            app.TX.ButtonPushedFcn = createCallbackFcn(app, @TXButtonPushed, true);
            app.TX.Text = 'TX';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComPortComms

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end